classdef JoystickMapping < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        GridLayout2                   matlab.ui.container.GridLayout
        GridLayout9                   matlab.ui.container.GridLayout
        TabGroup                      matlab.ui.container.TabGroup
        ControllerTuningTab           matlab.ui.container.Tab
        LQRPanel                      matlab.ui.container.Panel
        PIDPanel                      matlab.ui.container.Panel
        GridLayout16                  matlab.ui.container.GridLayout
        TuneButton                    matlab.ui.control.Button
        KdPsidotField                 matlab.ui.control.EditField
        KdThetadotField               matlab.ui.control.EditField
        KdThetaField                  matlab.ui.control.EditField
        KdPhidotField                 matlab.ui.control.EditField
        KdPhiField                    matlab.ui.control.EditField
        KdZdotField                   matlab.ui.control.EditField
        KiPsidotField                 matlab.ui.control.EditField
        KiThetadotField               matlab.ui.control.EditField
        KiThetaField                  matlab.ui.control.EditField
        KiPhidotField                 matlab.ui.control.EditField
        KiPhiField                    matlab.ui.control.EditField
        KiZdotField                   matlab.ui.control.EditField
        KpPsidotField                 matlab.ui.control.EditField
        KpThetadotField               matlab.ui.control.EditField
        KpThetaField                  matlab.ui.control.EditField
        KpPhidotField                 matlab.ui.control.EditField
        KpPhiField                    matlab.ui.control.EditField
        KpZdotField                   matlab.ui.control.EditField
        DLabel                        matlab.ui.control.Label
        ILabel                        matlab.ui.control.Label
        PLabel                        matlab.ui.control.Label
        YawRateLabel_2                matlab.ui.control.Label
        PitchRateLabel                matlab.ui.control.Label
        PitchAngleLabel               matlab.ui.control.Label
        RollRateLabel                 matlab.ui.control.Label
        RollAngleLabel                matlab.ui.control.Label
        VerticalVelocityLabel_2       matlab.ui.control.Label
        ReferenceCommandTab           matlab.ui.container.Tab
        GridLayout17                  matlab.ui.container.GridLayout
        GridLayout20_4                matlab.ui.container.GridLayout
        PitchAngleRefCmdImg           matlab.ui.control.SemicircularGauge
        GridLayout22_3                matlab.ui.container.GridLayout
        PitchCommanddegEditField      matlab.ui.control.EditField
        PitchCommanddegLabel          matlab.ui.control.Label
        GridLayout20_3                matlab.ui.container.GridLayout
        RollAngleRefCmdImg            matlab.ui.control.SemicircularGauge
        GridLayout22_2                matlab.ui.container.GridLayout
        RollCommanddegEditField       matlab.ui.control.EditField
        RollCommandLabel              matlab.ui.control.Label
        GridLayout20_2                matlab.ui.container.GridLayout
        YawRateRefCmdImg              matlab.ui.control.SemicircularGauge
        GridLayout22                  matlab.ui.container.GridLayout
        YawRateCommanddegsEditField   matlab.ui.control.EditField
        YawRateLabel                  matlab.ui.control.Label
        GridLayout20                  matlab.ui.container.GridLayout
        VerticalVelocityRefCmdImg     matlab.ui.control.Gauge
        GridLayout21                  matlab.ui.container.GridLayout
        VerticalVelocityCommandmsEditField  matlab.ui.control.EditField
        VerticalVelocityLabel         matlab.ui.control.Label
        CommandLimitationsPanel       matlab.ui.container.Panel
        GridLayout18                  matlab.ui.container.GridLayout
        GridLayout30                  matlab.ui.container.GridLayout
        LimitLamp                     matlab.ui.control.Lamp
        SetButton                     matlab.ui.control.Button
        GridLayout19                  matlab.ui.container.GridLayout
        PitchAngleLimitField          matlab.ui.control.NumericEditField
        VerticalVelocitymsEditField_2Label_4  matlab.ui.control.Label
        RollAngleLimitField           matlab.ui.control.NumericEditField
        VerticalVelocitymsEditField_2Label_3  matlab.ui.control.Label
        YawRateLimitField             matlab.ui.control.NumericEditField
        VerticalVelocitymsEditField_2Label_2  matlab.ui.control.Label
        VerticalVelocityLimitField    matlab.ui.control.NumericEditField
        VerticalVelocitymsEditField_2Label  matlab.ui.control.Label
        GridLayout10                  matlab.ui.container.GridLayout
        TabGroup2                     matlab.ui.container.TabGroup
        DAnimationTab                 matlab.ui.container.Tab
        GridLayout31                  matlab.ui.container.GridLayout
        DRotationAnimationPanel       matlab.ui.container.Panel
        GridLayout33                  matlab.ui.container.GridLayout
        UIAxes7                       matlab.ui.control.UIAxes
        DMovementAnimationPanel       matlab.ui.container.Panel
        GridLayout32                  matlab.ui.container.GridLayout
        UIAxes6                       matlab.ui.control.UIAxes
        ReferenceResponseTab_2        matlab.ui.container.Tab
        GridLayout34                  matlab.ui.container.GridLayout
        thetaResponse                 matlab.ui.control.UIAxes
        psidotResponse                matlab.ui.control.UIAxes
        phiResponse                   matlab.ui.control.UIAxes
        zdotResponse                  matlab.ui.control.UIAxes
        ResponseStateTab              matlab.ui.container.Tab
        GridLayout35                  matlab.ui.container.GridLayout
        psiVerTime                    matlab.ui.control.UIAxes
        thetaVerTime                  matlab.ui.control.UIAxes
        phiVerTime                    matlab.ui.control.UIAxes
        zVerTime                      matlab.ui.control.UIAxes
        yVerTime                      matlab.ui.control.UIAxes
        xVerTime                      matlab.ui.control.UIAxes
        GridLayout24                  matlab.ui.container.GridLayout
        TabGroup3                     matlab.ui.container.TabGroup
        ResponseStateCommandTab       matlab.ui.container.Tab
        GridLayout36                  matlab.ui.container.GridLayout
        ResponseCommandPanel          matlab.ui.container.Panel
        GridLayout25                  matlab.ui.container.GridLayout
        GridLayout28                  matlab.ui.container.GridLayout
        GridLayout29                  matlab.ui.container.GridLayout
        GridLayout27_8                matlab.ui.container.GridLayout
        PitchAngleResEditField        matlab.ui.control.EditField
        PitchAngleResLabel            matlab.ui.control.Label
        GridLayout27_3                matlab.ui.container.GridLayout
        RollAngleResEditField         matlab.ui.control.EditField
        RollAngleResEditFieldLabel    matlab.ui.control.Label
        ArtificialHorizon             Aero.ui.control.ArtificialHorizon
        GridLayout26                  matlab.ui.container.GridLayout
        GridLayout27_9                matlab.ui.container.GridLayout
        YawAngleRes                   matlab.ui.control.EditField
        VertVelocityResLabel_2        matlab.ui.control.Label
        GridLayout27                  matlab.ui.container.GridLayout
        VerticalVelocityRes           matlab.ui.control.EditField
        VertVelocityResLabel          matlab.ui.control.Label
        HeadingIndicator              Aero.ui.control.HeadingIndicator
        ClimbIndicator                Aero.ui.control.ClimbIndicator
        MotorResponseTab              matlab.ui.container.Tab
        GridLayout37                  matlab.ui.container.GridLayout
        GridLayout36_2                matlab.ui.container.GridLayout
        MotorSpeedPanel               matlab.ui.container.Panel
        GridLayout25_2                matlab.ui.container.GridLayout
        GridLayout26_3                matlab.ui.container.GridLayout
        GridLayout27_11               matlab.ui.container.GridLayout
        motorSpeedRes2                matlab.ui.control.EditField
        VertVelocityResLabel_4        matlab.ui.control.Label
        GridLayout27_10               matlab.ui.container.GridLayout
        motorSpeedRes1                matlab.ui.control.EditField
        VertVelocityResLabel_3        matlab.ui.control.Label
        motorSpeedIndicator2          Aero.ui.control.RPMIndicator
        motorSpeedIndicator1          Aero.ui.control.RPMIndicator
        GridLayout26_2                matlab.ui.container.GridLayout
        GridLayout27_13               matlab.ui.container.GridLayout
        motorSpeedRes4                matlab.ui.control.EditField
        VertVelocityResLabel_6        matlab.ui.control.Label
        GridLayout27_12               matlab.ui.container.GridLayout
        motorSpeedRes3                matlab.ui.control.EditField
        VertVelocityResLabel_5        matlab.ui.control.Label
        motorSpeedIndicator3          Aero.ui.control.RPMIndicator
        motorSpeedIndicator4          Aero.ui.control.RPMIndicator
        GridLayout3                   matlab.ui.container.GridLayout
        GridLayout4                   matlab.ui.container.GridLayout
        GridLayout7                   matlab.ui.container.GridLayout
        TabGroup4                     matlab.ui.container.TabGroup
        SensorTab                     matlab.ui.container.Tab
        GridLayout42                  matlab.ui.container.GridLayout
        SensorCalibrationPanel        matlab.ui.container.Panel
        GridLayout43                  matlab.ui.container.GridLayout
        StopConnectButton             matlab.ui.control.Button
        DroneConnectionLabel_2        matlab.ui.control.Label
        SensorDataLabel_2             matlab.ui.control.Label
        StopGetData                   matlab.ui.control.Button
        GetDataButton                 matlab.ui.control.Button
        SensorDataLabel               matlab.ui.control.Label
        SensorcalibrationLabel        matlab.ui.control.Label
        CalibButton                   matlab.ui.control.Button
        MS5611TextAreaLabel           matlab.ui.control.Label
        MS5611TextArea                matlab.ui.control.TextArea
        HMC5883LTextAreaLabel         matlab.ui.control.Label
        HMC5883LTextArea              matlab.ui.control.TextArea
        MPU6050TextArea               matlab.ui.control.TextArea
        MPU6050TextAreaLabel          matlab.ui.control.Label
        ControlTab                    matlab.ui.container.Tab
        GridLayout41                  matlab.ui.container.GridLayout
        GridLayout44                  matlab.ui.container.GridLayout
        CalibESCLabel                 matlab.ui.control.Label
        CalibEscLabel                 matlab.ui.control.Button
        ObtainButton                  matlab.ui.control.Button
        ResponseCommandLabel          matlab.ui.control.Label
        PlotRefResLabel               matlab.ui.control.Label
        Plot3DAnimationLabel          matlab.ui.control.Label
        StartPlotButton_2             matlab.ui.control.Button
        StartPlotButton               matlab.ui.control.Button
        StopMotorButton               matlab.ui.control.Button
        TurnOffMotorLabel             matlab.ui.control.Label
        TurnOnMotorLabel              matlab.ui.control.Label
        StartMotorButton              matlab.ui.control.Button
        JoysticksimulationConnectionPanel  matlab.ui.container.Panel
        GridLayout39                  matlab.ui.container.GridLayout
        ConnectDroneButton            matlab.ui.control.Button
        DroneConnectionLabel          matlab.ui.control.Label
        STOPButton                    matlab.ui.control.Button
        StopControlLabel              matlab.ui.control.Label
        STARTButton                   matlab.ui.control.Button
        StartControlLabel             matlab.ui.control.Label
        TimeSimulationEditField       matlab.ui.control.EditField
        TimeSimulationEditFieldLabel  matlab.ui.control.Label
        GridLayout5                   matlab.ui.container.GridLayout
        GridLayout15                  matlab.ui.container.GridLayout
        DisconnectButton              matlab.ui.control.Button
        comPortConfigLamp             matlab.ui.control.Lamp
        ConnectSerialButton           matlab.ui.control.Button
        GridLayout6                   matlab.ui.container.GridLayout
        TimeoutEditField              matlab.ui.control.NumericEditField
        TimeoutEditFieldLabel         matlab.ui.control.Label
        BaudrateDropDown              matlab.ui.control.DropDown
        BaudrateDropDownLabel         matlab.ui.control.Label
        SerialportDropDown            matlab.ui.control.DropDown
        SerialportDropDownLabel       matlab.ui.control.Label
        ComPortConfigurationLabel     matlab.ui.control.Label
        Image                         matlab.ui.control.Image
        ControllerLabel               matlab.ui.control.Label
    end

    
    properties (Access = private)
        getJoystickDataTimer;
        % status = AppStatus.Initializing;
        serialPort;
        isOnSerialPort                   = false;
        exchangeDataFlag                 = false;   
        startPlot3DRotationAnimationFlag = false;
        isPlotingFlag                    = false;
        startUpdateValueRefCmdUIFlag     = false;
        % isSendDataFlag                   = false;
        isSimulationRuning;
        
        % Data sending for control
        isConnectDrone  = 0;
        isCalibSensor   = 0;
        isGetSensorData = 0;
        isCalibESC      = 0;
        isStartMotor    = 0;
        isGetRes        = 0;
        isGetPlot3D     = 0;
        isGetPlotRefRes = 0;

         
        % Response Command 
        rollAngResDeg   = 0;
        pitchAngResDeg  = 0;
        yawAngResDeg    = 0;
        verticalVelRes  = 0; % m/s
        YawRateDegS     = 0; % deg/s

        motorSpeed1     = 0;
        motorSpeed2     = 0;
        motorSpeed3     = 0;
        motorSpeed4     = 0;
        
        isVehicleReady     = false;
        isStateMotorResImg = false;

        % Reference Command Limitation
        verticalVelocityLimit;
        yawRateLimit;
        rollAngleLimit;
        pitchAngleLimit;

        % Reference Command
        rollCommandDeg      = 0;
        pitchCommandDeg     = 0;
        throttleCommandDeg  = 0;
        yawCommandDeg       = 0;
        
        % 3d Plot
        lineRotation1;
        lineRotation2;
        lineRotation3;
        lineRotation4;

        motorRotation1;
        motorRotation2;
        motorRotation3;
        motorRotation4;
        
        frameRotation1;
        frameRotation2;
        frameRotation3;
        frameRotation4;
    end
    
    methods (Access = private)
         
        %% Helper Function
        % Convert double data to bit data for uart communication
        function bits = double2Bits(app, ref)
            d64 = double(ref);
            bits = (typecast(d64,'uint8'));
        end
        
        function bits = int82Bits(app, ref)
            i8 = int8(ref);
            bits = (typecast(i8,'uint8'));
        end

        % Check the validity of given cmd limitation 
        function flag = isValidValueLimitRefCmd(app)
            
            app.verticalVelocityLimit    = num2str(app.VerticalVelocityLimitField.Value);
            app.yawRateLimit             = num2str(app.YawRateLimitField.Value);
            app.rollAngleLimit           = num2str(app.RollAngleLimitField.Value);
            app.pitchAngleLimit          = num2str(app.PitchAngleLimitField.Value);

            if str2double(app.verticalVelocityLimit) >= 0
                flag = false;
            elseif str2double(app.yawRateLimit) <= 0
                flag = false;
            elseif str2double(app.rollAngleLimit) <= 0
                flag = false;
            elseif str2double(app.pitchAngleLimit) <= 0
                flag = false;
            else
                flag = true;
            end
        end
        
        %% Callback Function 
        % Callback function for serialport
        function SerialTerminFCN(app, ~, ~)
            app.exchangeDataFlag = true;
            
            Tx1 = int82Bits(app, app.isConnectDrone);   % 1 bit.
            Tx2 = int82Bits(app, app.isCalibSensor);    % 1 bit
            Tx3 = int82Bits(app, app.isGetSensorData);  % 1 bit
            Tx4 = int82Bits(app, app.isCalibESC);       % 1 bit
            Tx5 = int82Bits(app, app.isStartMotor);     % 1 bit
            Tx6 = int82Bits(app, app.isGetRes);         % 1 bit
            Tx7 = int82Bits(app, app.isGetPlot3D);      % 1 bit
            Tx8 = int82Bits(app, app.isGetPlotRefRes);  % 1 bit
              
            if app.isVehicleReady == true
                Tx9 = double2Bits(app, app.throttleCommandDeg);     % 8 bit
                Tx10 = double2Bits(app, app.yawCommandDeg);          % 8 bit
                Tx11 = double2Bits(app, app.rollCommandDeg);         % 8 bit
                Tx12 = double2Bits(app, app.pitchCommandDeg);       % 8 bit
            else 
                Tx9 = double2Bits(app, 0);
                Tx10 = double2Bits(app, 0);
                Tx11 = double2Bits(app, 0);
                Tx12 = double2Bits(app, 0);
            end
            
            assignin('base','isVehicleReady',app.isVehicleReady);
            
            write(app.serialPort, [Tx1 Tx2 Tx3 Tx4 Tx5 Tx6 Tx7 Tx8 Tx9 Tx10 Tx11 Tx12], "uint8"); % Tx3 Tx4
            assignin('base','dataRx',[Tx1 Tx2 Tx3 Tx4 Tx5 Tx6 Tx7 Tx8 Tx9 Tx10 Tx11 Tx12]); % Tx3 Tx4

            % if app.isVehicleReady ~= true
            %     write(app.serialPort, [Tx1 Tx2 Tx3 Tx4 Tx5 Tx6], "uint8");
            %     assignin('base','dataRx',[Tx1 Tx2 Tx3 Tx4 Tx5 Tx6]);
            % elseif app.isVehicleReady == true
            %     write(app.serialPort, [Tx1 Tx2 Tx3 Tx4 Tx5 Tx6 Tx7 Tx8 Tx9 Tx10], "uint8");
            %     assignin('base','dataRx',[Tx1 Tx2 Tx3 Tx4 Tx5 Tx6 Tx7 Tx8 Tx9 Tx10]);
            % end
            

            datachar = readline(app.serialPort);

            assignin('base', 'data', datachar);
            dataRx = str2double(strsplit(datachar, ','));

            % Data sending From board Configuration:
            % bit 1: isConnectVehicleFlag
            % bit 2: isCalibSensorFlag
            % bit 3: isStartSensorFlag
            % bit 4: isCalibMotorFlag
            % bit 5: isOnMotorFlag
            % bit 6: isStateResponseFlag
            % bit 7: isPlot3DAnimationFlag
            % bit 8: isPlotRefResFlag
            %----------------------------------------
            
            if length(dataRx) >= 2
                if (dataRx(2) > 1) 
                    app.MPU6050TextArea.Value = 'is Calibrated';
                    app.MPU6050TextArea.FontColor = [0.30,0.75,0.93];
                elseif (dataRx(2) == 1)
                    app.MPU6050TextArea.Value = 'is Calibrating';
                    app.MPU6050TextArea.FontColor = [0.39,0.83,0.07];
                end
    
                if (dataRx(2) > 3) 
                    app.HMC5883LTextArea.Value = 'is Calibrated';
                    app.HMC5883LTextArea.FontColor = [0.30,0.75,0.93];
                elseif (dataRx(2) == 3)
                    app.HMC5883LTextArea.Value = 'is Calibrating';
                    app.HMC5883LTextArea.FontColor = [0.39,0.83,0.07];
                end
    
                if (dataRx(2) > 7) 
                    app.MS5611TextArea.Value = 'is Calibrated';
                    app.MS5611TextArea.FontColor = [0.30,0.75,0.93];
                elseif (dataRx(2) == 5)
                    app.MS5611TextArea.Value = 'is Calibrating';
                    app.MS5611TextArea.FontColor = [0.39,0.83,0.07];
                end
                
                % Connect Vehicle and Start Sensor => vehicle is ready
                if dataRx(1) == 1 && dataRx(3) == 1  && ~app.isVehicleReady
                    app.isVehicleReady = true;       
                end
                if (dataRx(1) ~= 1 || dataRx(3) ~= 1) && app.isVehicleReady
                    app.isVehicleReady = false;
                end
                
                if app.isVehicleReady 
                    if dataRx(6) == 1
                        % Set flag for updating UI
                        app.isStateMotorResImg = true;
        
                        app.rollAngResDeg   = dataRx(9);
                        app.pitchAngResDeg  = dataRx(10);
                        app.yawAngResDeg    = dataRx(11);
                        app.verticalVelRes  = dataRx(12);
                        app.YawRateDegS     = dataRx(13);
                        
                        app.motorSpeed1 = dataRx(14);
                        app.motorSpeed2 = dataRx(15);
                        app.motorSpeed3 = dataRx(16);
                        app.motorSpeed4 = dataRx(17);
                        assignin('base', 'StateFlag', app.isStateMotorResImg);
                        assignin('base', 'roll', app.rollAngResDeg);
                        assignin('base', 'pitch', app.pitchAngResDeg);
                        assignin('base', 'VV', app.verticalVelRes);
                    elseif dataRx(7)
                    
                    elseif dataRx(8)
                    end
                end
                app.exchangeDataFlag = false;
            end
        end

        %% Process state of UI
        % function toggleUIControlState(app, state)
        %     uics = findall(app.GUI);
        %     for idx = 1:length(uics)
        %         uic = uics(idx);
        %         if isprop(uic,'Enable')
        %             uic.Enable = state;
        %         end
        %     end
        % end % toggleUIControlState
        
        % function switchStatus(app, newAppStatus)
        %     switch (newAppStatus)
        % 
        %         case AppStatus.Initializing
        %             assert(false,'Invalid newAppStatus');
        % 
        %         case AppStatus.Ready
        %             app.toggleUIControlState('on');
        %             app.StartStopButton.Text = 'Start';
        %             app.StartStopButton.BackgroundColor = [0.47,0.67,0.19];
        % 
        %         case AppStatus.Starting
        %             app.toggleUIControlState('off');
        %             app.StartStopButton.Text = 'Starting ...';
        %             app.clearSimStats();
        % 
        %             %------------------------------------------------------
        %             % Clear line and head marker
        %             clearpoints(app.hLine);
        %             set(app.hHead, 'XData',[], 'YData',[], 'ZData',[]);
        %             %------------------------------------------------------
        % 
        %         case AppStatus.Running
        %             app.toggleUIControlState('on');
        %             uics = findall(app.GUI,'Tag','DisableWhileRunning');
        %             set(uics,'Enable','off');
        %             app.StartStopButton.Text = 'Stop';
        %             app.StartStopButton.BackgroundColor = [0.85,0.33,0.10];
        %             app.SimStatsPanel.Visible = 'on';
        % 
        %         case AppStatus.Stopping
        %             app.toggleUIControlState('off');
        %             app.StartStopButton.Text = 'Stopping ...';
        %     end
        %     app.status = newAppStatus;
        %     drawnow limitrate;
        % end % switchStatus


        %% Update value entered directly from user to UI
        % Update Cmd Limitation 
        function updateLimitRefCmdUI(app)
            flagUpdateLimit = app.isValidValueLimitRefCmd();
            if flagUpdateLimit ~= true
                % Set default limitation    
                app.verticalVelocityLimit = '-3';
                app.yawRateLimit          = '15';
                app.rollAngleLimit        = '15';
                app.pitchAngleLimit       = '15';
            end
            app.VerticalVelocityRefCmdImg.ScaleDirection = 'counterclockwise';
            % assignin('base', 'VVLimit', app.verticalVelocityLimit);
            
            modelName = "Joystick";
            load_system(modelName);

            % Update cmd limitation for simulink model
            set_param(modelName+"/Yaw Extraction/Gain",'gain', app.yawRateLimit);
            set_param(modelName+"/Throttle Extraction/Gain",'gain', app.verticalVelocityLimit);
            set_param(modelName+"/Roll Extraction/Gain",'gain', app.rollAngleLimit);
            set_param(modelName+ "/Pitch Extraction/Gain",'gain', app.pitchAngleLimit);

            % Update cmd limitation for UI
            app.VerticalVelocityRefCmdImg.Limits   = [str2double(app.verticalVelocityLimit) 0];
            app.YawRateRefCmdImg.Limits            = [-str2double(app.yawRateLimit) str2double(app.yawRateLimit)];
            app.RollAngleRefCmdImg.Limits          = [-str2double(app.rollAngleLimit) str2double(app.rollAngleLimit)];
            app.PitchAngleRefCmdImg.Limits         = [-str2double(app.pitchAngleLimit) str2double(app.pitchAngleLimit)];
            % Change color of lammp when set new value
            if (app.LimitLamp.Color == [1.00,0.00,0.00])
                app.LimitLamp.Color = [0.00, 1.00, 0.00];
            elseif (app.LimitLamp.Color == [0.00, 1.00, 0.00])
                app.LimitLamp.Color = [1.00,0.00,0.00];
            end
        end

        %% Update feedback data
        function updateResponseCmd(app)
            % Roll Angle, Pitch Angle, Yaw Angle, VV, Yaw Rate
            % Update response state command UI
            
                assignin('base', 'run', true);
                % Update img value
                app.ClimbIndicator.ClimbRate       = -app.verticalVelRes*1000;
                app.HeadingIndicator.Value         = app.yawAngResDeg;
                app.ArtificialHorizon.Value        = [app.rollAngResDeg app.pitchAngResDeg];

                app.motorSpeedIndicator1.RPM       = app.motorSpeed1;
                app.motorSpeedIndicator2.RPM       = app.motorSpeed2;
                app.motorSpeedIndicator3.RPM       = app.motorSpeed3;
                app.motorSpeedIndicator4.RPM       = app.motorSpeed4;
                
                % Update text value
                app.VerticalVelocityRes.Value      = num2str(app.verticalVelRes);
                app.YawAngleRes.Value              = num2str(app.yawAngResDeg);
                app.RollAngleResEditField.Value    = num2str(app.rollAngResDeg);
                app.PitchAngleResEditField.Value   = num2str(app.pitchAngResDeg);
                
                app.motorSpeedRes1.Value           = num2str(app.motorSpeed1);
                app.motorSpeedRes2.Value           = num2str(app.motorSpeed2);
                app.motorSpeedRes3.Value           = num2str(app.motorSpeed3);
                app.motorSpeedRes4.Value           = num2str(app.motorSpeed4); 
        end
        

        %% Update control data
        % Show data from joystick on UI
        function updateValueRefCmdUI(app)
            modelName = "Joystick";
            
            outputData1 = get_param(modelName + '/Roll Command','RuntimeObject');
            outputData2 = get_param(modelName + '/Pitch Command','RuntimeObject');
            outputData3 = get_param(modelName + '/Throttle Command','RuntimeObject');
            outputData4 = get_param(modelName + '/Yaw Command','RuntimeObject');

            % OutputPort: Get data from input port in runtime
            % InputPort: Get data from output port in runtime

            app.rollCommandDeg     = outputData1.InputPort(1).Data; 
            app.pitchCommandDeg    = outputData2.InputPort(1).Data; 
            app.throttleCommandDeg = outputData3.InputPort(1).Data;
            app.yawCommandDeg      = outputData4.InputPort(1).Data; 
           
            % Update UI
            app.VerticalVelocityRefCmdImg.Value  = app.throttleCommandDeg;
            app.YawRateRefCmdImg.Value           = app.yawCommandDeg;
            app.RollAngleRefCmdImg.Value         = app.rollCommandDeg;
            app.PitchAngleRefCmdImg.Value        = app.pitchCommandDeg;

            % Update Edit Field
            app.VerticalVelocityCommandmsEditField.Value  = num2str(app.throttleCommandDeg);
            app.YawRateCommanddegsEditField.Value         = num2str(app.yawCommandDeg);
            app.RollCommanddegEditField.Value             = num2str(app.rollCommandDeg);
            app.PitchCommanddegEditField.Value            = num2str(app.pitchCommandDeg);
            
        end

        function updatePlot3DRotationAnimcation(app)
            
                state = [0 0 0 0 app.rollAngResDeg*pi/180 app.pitchAngResDeg*pi/180 app.yawAngResDeg*pi/180];
                XYZ = state(:,2:4);
                Euler = state(:,5:7);
                % t = state(:,1);
            
                % Create data for circle
                % Độ phân giải của đường tròn (số lượng điểm trên đường tròn)
                resolution = 100;
    
                % Bán kính của đường tròn
                radius = 0.5;
                
                % Tạo mảng theta chứa các góc theta từ 0 đến 2*pi với độ phân giải đã cho
                theta = linspace(0, 2*pi, resolution);
                
                % Tính toán các tọa độ x, y của các điểm trên đường tròn
                x_circle = radius * cos(theta);
                y_circle = radius * sin(theta);
                
                % Tạo mảng z chứa các tọa độ z của các điểm trên đường tròn (nếu cần)
                z_circle = zeros(size(theta));
                
                % Data for plot 3D Movement Animation
                droneFrame = [
                  XYZ(1,1) XYZ(1,1)+2 XYZ(1,1)-2 XYZ(1,1)-2 XYZ(1,1)+2 XYZ(1,1)+2.5 XYZ(1,1)     XYZ(1,1)     XYZ(1,1)+2+x_circle XYZ(1,1)-2+x_circle XYZ(1,1)-2+x_circle XYZ(1,1)+2+x_circle ;
                  XYZ(1,2) XYZ(1,2)+2 XYZ(1,2)+2 XYZ(1,2)-2 XYZ(1,2)-2 XYZ(1,2)     XYZ(1,2)+2.5 XYZ(1,2)     XYZ(1,2)+2+y_circle XYZ(1,2)+2+y_circle XYZ(1,2)-2+y_circle XYZ(1,2)-2+y_circle ;
                  XYZ(1,3) XYZ(1,3)   XYZ(1,3)   XYZ(1,3)   XYZ(1,3)   XYZ(1,3)     XYZ(1,3)     XYZ(1,3)+1   XYZ(1,3)+z_circle   XYZ(1,3)+z_circle   XYZ(1,3)+z_circle   XYZ(1,3)+z_circle;
                  1 1 1 1 1 1 1 1 ones(size(theta)) ones(size(theta)) ones(size(theta)) ones(size(theta));];
                
                % Xoay quanh truc toa do Oz
                angleZ = Euler(1,3); % angle about Oz
                angleY = Euler(1,2); % angle about Oy
                angleX = Euler(1,1); % angle about Ox
                Rz = [cos(angleZ) -sin(angleZ) 0 0
                    sin(angleZ) cos(angleZ) 0 0
                    0 0 1 0
                    0 0 0 1];
                % Xoay quanh truc toa do Oy
                Ry = [cos(angleY) 0 sin(angleY) 0
                     0 1 0 0 
                     -sin(angleY) 0 cos(angleY) 0
                     0 0 0 1];
                % Xoay quanh truc toa do Ox
                Rx = [1 0 0 0
                     0 cos(angleX) -sin(angleX) 0
                     0 sin(angleX) cos(angleX) 0
                     0 0 0 1];
                R = Rx*Ry*Rz;
    
                droneFrameRotation = R*droneFrame;
                
                % Delete old figure
                delete(app.lineRotation1)
                delete(app.lineRotation2)
                delete(app.lineRotation3)
        
                delete(app.motorRotation1)
                delete(app.motorRotation2)
                delete(app.motorRotation3)
                delete(app.motorRotation4)
        
                delete(app.frameRotation1)
                delete(app.frameRotation2)
                delete(app.frameRotation3)
                delete(app.frameRotation4)
    
    
                % Plot 3D rotation animation
                app.motorRotation1 = plot3(app.UIAxes7, droneFrameRotation(1,9:8+size(theta,2)),                   droneFrameRotation(2,9:8+size(theta,2)),                   droneFrameRotation(3,9:8+size(theta,2)),'cyan','LineWidth',2.5);
                app.motorRotation2 = plot3(app.UIAxes7, droneFrameRotation(1,9+size(theta,2):8+size(theta,2)*2),   droneFrameRotation(2,9+size(theta,2):8+size(theta,2)*2),   droneFrameRotation(3,9+size(theta,2):8+size(theta,2)*2),'cyan','LineWidth',2.5);
                app.motorRotation3 = plot3(app.UIAxes7, droneFrameRotation(1,9+size(theta,2)*2:8+size(theta,2)*3), droneFrameRotation(2,9+size(theta,2)*2:8+size(theta,2)*3), droneFrameRotation(3,9+size(theta,2)*2:8+size(theta,2)*3),'cyan','LineWidth',2.5);
                app.motorRotation4 = plot3(app.UIAxes7, droneFrameRotation(1,9+size(theta,2)*3:8+size(theta,2)*4), droneFrameRotation(2,9+size(theta,2)*3:8+size(theta,2)*4), droneFrameRotation(3,9+size(theta,2)*3:8+size(theta,2)*4),'cyan','LineWidth',2.5);
    
                pts = [droneFrameRotation(1:3,1)'
                    droneFrameRotation(1:3,2)'];
                app.frameRotation1 = plot3(app.UIAxes7, pts(:,1), pts(:,2), pts(:,3),'black','LineWidth',2.5);
                pts = [droneFrameRotation(1:3,1)'
                    droneFrameRotation(1:3,3)'];
                app.frameRotation2 = plot3(app.UIAxes7, pts(:,1), pts(:,2), pts(:,3),'black','LineWidth',2.5);
                pts = [droneFrameRotation(1:3,1)'
                    droneFrameRotation(1:3,4)'];
                app.frameRotation3 = plot3(app.UIAxes7, pts(:,1), pts(:,2), pts(:,3),'black','LineWidth',2.5);
                pts = [droneFrameRotation(1:3,1)'
                    droneFrameRotation(1:3,5)'];
                app.frameRotation4 = plot3(app.UIAxes7, pts(:,1), pts(:,2), pts(:,3),'black','LineWidth',2.5);
    
                pts = [droneFrameRotation(1:3,1)'
                    droneFrameRotation(1:3,6)'];
                app.lineRotation1 = plot3(app.UIAxes7, pts(:,1), pts(:,2), pts(:,3),'b','LineWidth',1);
                pts = [droneFrameRotation(1:3,1)'
                    droneFrameRotation(1:3,7)'];
                app.lineRotation2 = plot3(app.UIAxes7, pts(:,1), pts(:,2), pts(:,3),'g','LineWidth',1);
                pts = [droneFrameRotation(1:3,1)'
                    droneFrameRotation(1:3,8)'];
                app.lineRotation3 = plot3(app.UIAxes7, pts(:,1), pts(:,2), pts(:,3),'r','LineWidth',1);
    
                drawnow limitrate
        end

        % throttleCmd, yawCmd, rollCmd, pitchCmd, status
        function readJoystickDataTimerFcn(app, ~, event)
            if (app.isStateMotorResImg)
                app.updateResponseCmd();
            end
            
            if (app.startUpdateValueRefCmdUIFlag)
               app.updateValueRefCmdUI();
            end

            if (app.startPlot3DRotationAnimationFlag)
                app.updatePlot3DRotationAnimcation();
            end

            if (~app.exchangeDataFlag && app.isOnSerialPort) % 
                Tx1 = int82Bits(app, app.isConnectDrone);   % 1 bit.
                Tx2 = int82Bits(app, app.isCalibSensor);    % 1 bit
                Tx3 = int82Bits(app, app.isGetSensorData);  % 1 bit
                Tx4 = int82Bits(app, app.isCalibESC);       % 1 bit
                Tx5 = int82Bits(app, app.isStartMotor);     % 1 bit
                Tx6 = int82Bits(app, app.isGetRes);         % 1 bit
                Tx7 = int82Bits(app, app.isGetPlot3D);      % 1 bit
                Tx8 = int82Bits(app, app.isGetPlotRefRes);  % 1 bit
                if app.isVehicleReady == true
                    Tx9 = double2Bits(app, app.throttleCommandDeg);     % 8 bit
                    Tx10 = double2Bits(app, app.yawCommandDeg);          % 8 bit
                    Tx11 = double2Bits(app, app.rollCommandDeg);         % 8 bit
                    Tx12 = double2Bits(app, app.pitchCommandDeg);       % 8 bit
                else 
                    Tx9 = double2Bits(app, 0);
                    Tx10 = double2Bits(app, 0);
                    Tx11 = double2Bits(app, 0);
                    Tx12 = double2Bits(app, 0);
                end

                write(app.serialPort, [Tx1 Tx2 Tx3 Tx4 Tx5 Tx6 Tx7 Tx8 Tx9 Tx10 Tx11 Tx12], "uint8"); % Tx3 Tx4
                assignin('base','dataRx',[Tx1 Tx2 Tx3 Tx4 Tx5 Tx6 Tx7 Tx8 Tx9 Tx10 Tx11 Tx12]); % Tx3 Tx4

            end
                assignin('base','exchangeDataFlag',app.exchangeDataFlag); % Tx3 Tx4
            
        end
        function postStepFcn(app)
            % Called during sim after each simulation time step
            
            app.VerticalVelocityCommandmsEditField.Value = num2str(app.throttleCommandDeg);
            app.YawRateCommanddegsEditField.Value          = num2str(app.yawCommandDeg);
            app.RollCommanddegEditField.Value             = num2str(app.rollCommandDeg);
            app.PitchCommanddegEditField.Value            = num2str(app.pitchCommandDeg);
            app.ClimbIndicator.ClimbRate   = -app.throttleCommandDeg*1000;
            app.HeadingIndicator.Value     = app.yawCommandDeg;
            app.ArtificialHorizon.Value  = [app.rollCommandDeg app.pitchCommandDeg];
        end % postStepFcn
        
        function processOutputs(app, opIdx, ~, data)
            % app.rollCommandDeg     = app.simOut.yout{1}.Values.Data;
            % app.pitchCommandDeg    = app.simOut.yout{2}.Values.Data;
            % app.throttleCommandDeg = app.simOut.yout{3}.Values.Data;
            % app.yawCommandDeg      = app.simOut.yout{4}.Values.Data;
            % Called during sim to process the external output data.
            switch opIdx
                case 1
                    app.rollCommandDeg = data;
                case 2
                    app.pitchCommandDeg = data;
                case 3
                    app.throttleCommandDeg = data;
                case 4
                    app.yawCommandDeg = data;
                otherwise
                    error(['Invalid port index: ', num2str(opIdx)]);
            end
        end % processOutputs

        function simInp = createSimulationInput(app)
            % Create an empty SimulationInput object
            simInp = Simulink.SimulationInput("Joystick");
            
            % Specify simulation callbacks
            simInp = simulink.compiler.setPostStepFcn(simInp, @app.postStepFcn);
            simInp = simulink.compiler.setExternalOutputsFcn(simInp, @app.processOutputs);
            
            %--------------------------------------------------------------
            % Load the parameters values from the ui edit fields
            % simInp = simInp.setVariable('rho',app.rhoUIC.Value);
            % simInp = simInp.setVariable('beta',app.betaUIC.Value);
            % simInp = simInp.setVariable('sigma',app.sigmaUIC.Value);
            % simInp = simInp.setVariable('x0',app.x0UIC.Value);
            % simInp = simInp.setVariable('y0',app.y0UIC.Value);
            % simInp = simInp.setVariable('z0',app.z0UIC.Value);

            stopTimeStr = num2str(app.TimeSimulationEditField.Value);
            simInp = setModelParameter(simInp,"StopTime", stopTimeStr);

            %--------------------------------------------------------------
            
            % Configure simInp for deployment
            % DEBUG TIP: Comment out the line below for
            % faster/easier debugging when runnng in MATLAB
            simInp = simulink.compiler.configureForDeployment(simInp);
        end % createSimulationInput

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % https://www.mathworks.com/help/matlab/creating_guis/wind-speed-gui-in-app-designer.html
            % https://www.mathworks.com/help/matlab/matlab_prog/timer-callback-functions.html#f9-39575
            
            %% Create a timer object
            % Use timer object for updating separately command data from joystick
            % without depending on whether serialport is on or not
            app.getJoystickDataTimer = timer("ExecutionMode","fixedRate","Period",0.01, ...
                                   "BusyMode","queue","TimerFcn",@app.readJoystickDataTimerFcn);
            
            % Initialization 3d Plot
            set(app.UIAxes7, 'Zdir', 'reverse');
            plot3(app.UIAxes7, nan, nan, nan,'b-');
            hold(app.UIAxes7, "on");
            grid(app.UIAxes7, "on");
            axis(app.UIAxes7, "padded"); % padded
            
            zlimit = [-5 5];
            set(app.UIAxes7, 'ZLim',zlimit);
            
            app.lineRotation1 = plot3(app.UIAxes7,0,0,0);
            app.lineRotation2 = plot3(app.UIAxes7,0,0,0);
            app.lineRotation3 = plot3(app.UIAxes7,0,0,0);
            app.lineRotation4 = plot3(app.UIAxes7,0,0,0);
    
            app.motorRotation1 = plot3(app.UIAxes7,0,0,0);
            app.motorRotation2 = plot3(app.UIAxes7,0,0,0);
            app.motorRotation3 = plot3(app.UIAxes7,0,0,0);
            app.motorRotation4 = plot3(app.UIAxes7,0,0,0);
            
            app.frameRotation1 = plot3(app.UIAxes7,0,0,0);
            app.frameRotation2 = plot3(app.UIAxes7,0,0,0);
            app.frameRotation3 = plot3(app.UIAxes7,0,0,0);
            app.frameRotation4 = plot3(app.UIAxes7,0,0,0);
        end

        % Button pushed function: ConnectSerialButton
        function ConnectSerialButtonPushed(app, event)
            

            % Create serialport and start connection
            app.serialPort = serialport(app.SerialportDropDown.Value,str2double(app.BaudrateDropDown.Value), "Timeout", app.TimeoutEditField.Value);
            configureTerminator(app.serialPort, "CR/LF");
            configureCallback(app.serialPort,"terminator", @app.SerialTerminFCN)    
            
            % Turn on serial port flag
            app.isOnSerialPort = true;

            % Turn light into green
            app.comPortConfigLamp.Color = [0.00, 1.00, 0.00];

            Tx1 = int82Bits(app, app.isConnectDrone);   % 1 bit.
            Tx2 = int82Bits(app, app.isCalibSensor);    % 1 bit
            Tx3 = int82Bits(app, app.isGetSensorData);  % 1 bit
            Tx4 = int82Bits(app, app.isCalibESC);       % 1 bit
            Tx5 = int82Bits(app, app.isStartMotor);     % 1 bit
            Tx6 = int82Bits(app, app.isGetRes);         % 1 bit
            Tx7 = int82Bits(app, app.isGetPlot3D);      % 1 bit
            Tx8 = int82Bits(app, app.isGetPlotRefRes);  % 1 bit
            if app.isVehicleReady == true
                Tx9 = double2Bits(app, app.throttleCommandDeg);     % 8 bit
                Tx10 = double2Bits(app, app.yawCommandDeg);          % 8 bit
                Tx11 = double2Bits(app, app.rollCommandDeg);         % 8 bit
                Tx12 = double2Bits(app, app.pitchCommandDeg);       % 8 bit
            else 
                Tx9 = double2Bits(app, 0);
                Tx10 = double2Bits(app, 0);
                Tx11 = double2Bits(app, 0);
                Tx12 = double2Bits(app, 0);
            end

            write(app.serialPort, [Tx1 Tx2 Tx3 Tx4 Tx5 Tx6 Tx7 Tx8 Tx9 Tx10 Tx11 Tx12], "uint8"); % Tx3 Tx4
            assignin('base','dataRx',[Tx1 Tx2 Tx3 Tx4 Tx5 Tx6 Tx7 Tx8 Tx9 Tx10 Tx11 Tx12]); % Tx3 Tx4

            
            % Start the timer object
            if strcmp(app.getJoystickDataTimer.Running,"off")
                start(app.getJoystickDataTimer);
            end

        end

        % Button pushed function: STARTButton
        function STARTButtonPushed(app, event)
            % simInp = app.createSimulationInput();
            % % Run
            % sim(simInp);
            
            %% ----------------------------------------------------------------------------------
           

            % Get data from user input
            timeSimulation = app.TimeSimulationEditField.Value;
            modelName = "Joystick";
            
            % ----------------------------------------------------------------------------
          
            set_param(modelName, "StopTime", timeSimulation);
            set_param(modelName, SimulationCommand="start");
            % ---------------------------------------------------------------------------
            
            % Update Reference Command
            % app.updateValueRefCmdUI();

            % Specify the value of the TimerFcn callback.
            % app.getJoystickDataTimer.TimerFcn = {@app.readJoystickDataTimerFcn, throttleCommandDeg, ...
            %                                     yawCommandDeg, rollCommandDeg, pitchCommandDeg, app.isSimulationRuning};

             % Start the timer object
            if strcmp(app.getJoystickDataTimer.Running,"off")
                start(app.getJoystickDataTimer);
            end
            
            pause(1);
            app.startUpdateValueRefCmdUIFlag = true;
        end

        % Button pushed function: STOPButton
        function STOPButtonPushed(app, event)
            % Stop the timer object
            stop(app.getJoystickDataTimer);
            
            app.startUpdateValueRefCmdUIFlag = false;

            % Stop the simulation
            modelName = "Joystick";
            set_param(modelName,SimulationCommand="stop");


        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            
            delete(app.getJoystickDataTimer);
            delete(app)
            
        end

        % Button pushed function: DisconnectButton
        function DisconnectButtonPushed(app, event)
            % Stop the timer object
            stop(app.getJoystickDataTimer);
            
            app.isOnSerialPort = false;
            pause(0.5);

            delete(app.serialPort)
            app.comPortConfigLamp.Color = [1.00, 0.00, 0.00];
            
            % StopButtonPushed(app);
            % setAppState(app,"DeviceSelection");
        end

        % Button pushed function: SetButton
        function SetButtonPushed(app, event)
            % Update limitation of reference command
            app.updateLimitRefCmdUI();
        end

        % Button pushed function: ConnectDroneButton
        function ConnectDroneButtonPushed(app, event)

            app.isConnectDrone = 1;
           
            % % Start the timer object
            % if strcmp(app.getJoystickDataTimer.Running,"off")
            %     start(app.getJoystickDataTimer);
            % end
        end

        % Button pushed function: CalibButton
        function CalibButtonPushed(app, event)
            app.isCalibSensor = 1;
        end

        % Button pushed function: StartMotorButton
        function StartMotorButtonPushed(app, event)
            app.isCalibESC = 0;
            app.isStartMotor = 1;
        end

        % Button pushed function: StopMotorButton
        function StopMotorButtonPushed(app, event)
    
            app.isStartMotor = 0;
        end

        % Button pushed function: ObtainButton
        function ObtainButtonPushed(app, event)
            app.isGetRes = 1;
        end

        % Button pushed function: StartPlotButton
        function StartPlotButtonPushed(app, event)
            if (~app.isPlotingFlag)
                app.StartPlotButton.Text             = "Stop Plot";
                app.StartPlotButton.BackgroundColor  = [0.85,0.33,0.10];
                app.startPlot3DRotationAnimationFlag = true;
                app.isPlotingFlag                    = true;
            else
                app.StartPlotButton.Text             = "Start Plot";
                app.StartPlotButton.BackgroundColor  = [0.47,0.67,0.19];
                app.startPlot3DRotationAnimationFlag = false;
                app.isPlotingFlag                    = false;
            end

        end

        % Button pushed function: StartPlotButton_2
        function StartPlotButton_2Pushed(app, event)
            
        end

        % Button pushed function: GetDataButton
        function GetDataButtonPushed(app, event)
            app.isCalibSensor = 0;
            app.isGetSensorData = 1;
        end

        % Button pushed function: CalibEscLabel
        function CalibEscLabelButtonPushed(app, event)
            app.isCalibESC = 1;
        end

        % Button pushed function: StopGetData
        function StopGetDataButtonPushed(app, event)
            app.isCalibSensor = 0;
            app.isGetSensorData = 0;
        end

        % Button pushed function: StopConnectButton
        function StopConnectButtonPushed(app, event)
            app.isConnectDrone = 0;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.ControllerLabel = uilabel(app.GridLayout);
            app.ControllerLabel.Text = 'Controller';
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.GridLayout3 = uigridlayout(app.GridLayout2);
            app.Image = uiimage(app.GridLayout3);
            app.GridLayout4 = uigridlayout(app.GridLayout3);
            app.GridLayout5 = uigridlayout(app.GridLayout4);
            app.ComPortConfigurationLabel = uilabel(app.GridLayout5);
            app.ComPortConfigurationLabel.Text = 'ComPort Configuration';
            app.GridLayout6 = uigridlayout(app.GridLayout5);
            app.SerialportDropDownLabel = uilabel(app.GridLayout6);
            app.SerialportDropDownLabel.Text = 'Serial port';
            app.SerialportDropDown = uidropdown(app.GridLayout6);
            app.SerialportDropDown.Items = {'COM1', 'COM2', 'COM3', 'COM4', 'COM5', 'COM6', 'COM7', 'COM8', 'COM9', 'COM10', 'COM11', 'COM12', 'COM13', 'COM14', 'COM15', 'COM16', 'COM17', 'COM18', 'COM19', 'COM20', 'COM21', 'COM22', 'COM23', 'COM24', 'COM25'};
            app.SerialportDropDown.Value = 'COM1';
            app.BaudrateDropDownLabel = uilabel(app.GridLayout6);
            app.BaudrateDropDownLabel.Text = 'Baud rate';
            app.BaudrateDropDown = uidropdown(app.GridLayout6);
            app.BaudrateDropDown.Items = {'4800', '9600', '14400', '19200', '38400', '56000', '57600', '115200'};
            app.BaudrateDropDown.Value = '115200';
            app.TimeoutEditFieldLabel = uilabel(app.GridLayout6);
            app.TimeoutEditFieldLabel.Text = 'Time out';
            app.TimeoutEditField = uieditfield(app.GridLayout6, 'numeric');
            app.TimeoutEditField.Value = 10;
            app.GridLayout15 = uigridlayout(app.GridLayout5);
            app.ConnectSerialButton = uibutton(app.GridLayout15, 'push');
            app.ConnectSerialButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectSerialButtonPushed, true);
            app.ConnectSerialButton.Text = 'Connect';
            app.comPortConfigLamp = uilamp(app.GridLayout15);
            app.DisconnectButton = uibutton(app.GridLayout15, 'push');
            app.DisconnectButton.ButtonPushedFcn = createCallbackFcn(app, @DisconnectButtonPushed, true);
            app.DisconnectButton.Text = 'Disconnect';
            app.GridLayout7 = uigridlayout(app.GridLayout4);
            app.JoysticksimulationConnectionPanel = uipanel(app.GridLayout7);
            app.JoysticksimulationConnectionPanel.Title = 'Joystick simulation & Connection';
            app.GridLayout39 = uigridlayout(app.JoysticksimulationConnectionPanel);
            app.TimeSimulationEditFieldLabel = uilabel(app.GridLayout39);
            app.TimeSimulationEditFieldLabel.Text = 'Time Simulation';
            app.TimeSimulationEditField = uieditfield(app.GridLayout39, 'text');
            app.StartControlLabel = uilabel(app.GridLayout39);
            app.StartControlLabel.Text = 'Start Control';
            app.STARTButton = uibutton(app.GridLayout39, 'push');
            app.STARTButton.ButtonPushedFcn = createCallbackFcn(app, @STARTButtonPushed, true);
            app.STARTButton.Text = 'START';
            app.StopControlLabel = uilabel(app.GridLayout39);
            app.StopControlLabel.Text = 'Stop Control';
            app.STOPButton = uibutton(app.GridLayout39, 'push');
            app.STOPButton.ButtonPushedFcn = createCallbackFcn(app, @STOPButtonPushed, true);
            app.STOPButton.Text = 'STOP';
            app.DroneConnectionLabel = uilabel(app.GridLayout39);
            app.DroneConnectionLabel.Text = 'Drone Connection';
            app.ConnectDroneButton = uibutton(app.GridLayout39, 'push');
            app.ConnectDroneButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectDroneButtonPushed, true);
            app.ConnectDroneButton.Text = 'Connect';
            app.TabGroup4 = uitabgroup(app.GridLayout7);
            app.SensorTab = uitab(app.TabGroup4);
            app.SensorTab.Title = 'Sensor';
            app.GridLayout42 = uigridlayout(app.SensorTab);
            app.SensorCalibrationPanel = uipanel(app.GridLayout42);
            app.SensorCalibrationPanel.Title = 'Sensor Calibration';
            app.GridLayout43 = uigridlayout(app.SensorCalibrationPanel);
            app.MPU6050TextAreaLabel = uilabel(app.GridLayout43);
            app.MPU6050TextAreaLabel.Text = 'MPU6050';
            app.MPU6050TextArea = uitextarea(app.GridLayout43);
            app.MPU6050TextArea.Value = {'Not Calibrated'};
            app.HMC5883LTextArea = uitextarea(app.GridLayout43);
            app.HMC5883LTextArea.Value = {'Not Calibrated'};
            app.HMC5883LTextAreaLabel = uilabel(app.GridLayout43);
            app.HMC5883LTextAreaLabel.Text = 'HMC5883L';
            app.MS5611TextArea = uitextarea(app.GridLayout43);
            app.MS5611TextArea.Value = {'Not Calibrated'};
            app.MS5611TextAreaLabel = uilabel(app.GridLayout43);
            app.MS5611TextAreaLabel.Text = 'MS5611';
            app.CalibButton = uibutton(app.GridLayout43, 'push');
            app.CalibButton.ButtonPushedFcn = createCallbackFcn(app, @CalibButtonPushed, true);
            app.CalibButton.Text = 'Calib';
            app.SensorcalibrationLabel = uilabel(app.GridLayout43);
            app.SensorcalibrationLabel.Text = 'Sensor calibration';
            app.SensorDataLabel = uilabel(app.GridLayout43);
            app.SensorDataLabel.Text = 'Sensor Data:';
            app.GetDataButton = uibutton(app.GridLayout43, 'push');
            app.GetDataButton.ButtonPushedFcn = createCallbackFcn(app, @GetDataButtonPushed, true);
            app.GetDataButton.Text = 'Get Data';
            app.StopGetData = uibutton(app.GridLayout43, 'push');
            app.StopGetData.ButtonPushedFcn = createCallbackFcn(app, @StopGetDataButtonPushed, true);
            app.StopGetData.Text = 'Stop Get Data';
            app.SensorDataLabel_2 = uilabel(app.GridLayout43);
            app.SensorDataLabel_2.Text = 'Sensor Data:';
            app.DroneConnectionLabel_2 = uilabel(app.GridLayout43);
            app.DroneConnectionLabel_2.Text = 'Drone Connection';
            app.StopConnectButton = uibutton(app.GridLayout43, 'push');
            app.StopConnectButton.ButtonPushedFcn = createCallbackFcn(app, @StopConnectButtonPushed, true);
            app.StopConnectButton.Text = 'Stop Connect';
            app.ControlTab = uitab(app.TabGroup4);
            app.ControlTab.Title = 'Control';
            app.GridLayout41 = uigridlayout(app.ControlTab);
            app.GridLayout44 = uigridlayout(app.GridLayout41);
            app.StartMotorButton = uibutton(app.GridLayout44, 'push');
            app.StartMotorButton.ButtonPushedFcn = createCallbackFcn(app, @StartMotorButtonPushed, true);
            app.StartMotorButton.Text = 'Start Motor';
            app.TurnOnMotorLabel = uilabel(app.GridLayout44);
            app.TurnOnMotorLabel.Text = 'Turn On Motor';
            app.TurnOffMotorLabel = uilabel(app.GridLayout44);
            app.TurnOffMotorLabel.Text = 'Turn Off Motor';
            app.StopMotorButton = uibutton(app.GridLayout44, 'push');
            app.StopMotorButton.ButtonPushedFcn = createCallbackFcn(app, @StopMotorButtonPushed, true);
            app.StopMotorButton.Text = 'Stop Motor';
            app.StartPlotButton = uibutton(app.GridLayout44, 'push');
            app.StartPlotButton.ButtonPushedFcn = createCallbackFcn(app, @StartPlotButtonPushed, true);
            app.StartPlotButton.Text = 'Start Plot';
            app.StartPlotButton_2 = uibutton(app.GridLayout44, 'push');
            app.StartPlotButton_2.ButtonPushedFcn = createCallbackFcn(app, @StartPlotButton_2Pushed, true);
            app.StartPlotButton_2.Text = 'Start Plot';
            app.Plot3DAnimationLabel = uilabel(app.GridLayout44);
            app.Plot3DAnimationLabel.Text = 'Plot 3D Animation';
            app.PlotRefResLabel = uilabel(app.GridLayout44);
            app.PlotRefResLabel.Text = 'Plot Ref & Res';
            app.ResponseCommandLabel = uilabel(app.GridLayout44);
            app.ResponseCommandLabel.Text = 'Response Command';
            app.ObtainButton = uibutton(app.GridLayout44, 'push');
            app.ObtainButton.ButtonPushedFcn = createCallbackFcn(app, @ObtainButtonPushed, true);
            app.ObtainButton.Text = 'Obtain';
            app.CalibEscLabel = uibutton(app.GridLayout44, 'push');
            app.CalibEscLabel.ButtonPushedFcn = createCallbackFcn(app, @CalibEscLabelButtonPushed, true);
            app.CalibEscLabel.Text = 'Calib ESC';
            app.CalibESCLabel = uilabel(app.GridLayout44);
            app.CalibESCLabel.Text = 'Calib ESC';
            app.GridLayout9 = uigridlayout(app.GridLayout2);
            app.GridLayout10 = uigridlayout(app.GridLayout9);
            app.GridLayout24 = uigridlayout(app.GridLayout10);
            app.TabGroup3 = uitabgroup(app.GridLayout24);
            app.ResponseStateCommandTab = uitab(app.TabGroup3);
            app.ResponseStateCommandTab.Title = 'Response State Command';
            app.GridLayout36 = uigridlayout(app.ResponseStateCommandTab);
            app.ResponseCommandPanel = uipanel(app.GridLayout36);
            app.ResponseCommandPanel.Title = 'Response Command';
            app.GridLayout25 = uigridlayout(app.ResponseCommandPanel);
            app.GridLayout26 = uigridlayout(app.GridLayout25);
            app.ClimbIndicator = uiaeroclimb(app.GridLayout26);
            app.HeadingIndicator = uiaeroheading(app.GridLayout26);
            app.GridLayout27 = uigridlayout(app.GridLayout26);
            app.VertVelocityResLabel = uilabel(app.GridLayout27);
            app.VertVelocityResLabel.Text = 'Vert Velocity Res';
            app.VerticalVelocityRes = uieditfield(app.GridLayout27, 'text');
            app.GridLayout27_9 = uigridlayout(app.GridLayout26);
            app.VertVelocityResLabel_2 = uilabel(app.GridLayout27_9);
            app.VertVelocityResLabel_2.Text = 'Yaw Angle Res';
            app.YawAngleRes = uieditfield(app.GridLayout27_9, 'text');
            app.GridLayout28 = uigridlayout(app.GridLayout25);
            app.GridLayout29 = uigridlayout(app.GridLayout28);
            app.ArtificialHorizon = uiaerohorizon(app.GridLayout29);
            app.GridLayout27_3 = uigridlayout(app.GridLayout29);
            app.RollAngleResEditFieldLabel = uilabel(app.GridLayout27_3);
            app.RollAngleResEditFieldLabel.Text = 'Roll Angle Res';
            app.RollAngleResEditField = uieditfield(app.GridLayout27_3, 'text');
            app.GridLayout27_8 = uigridlayout(app.GridLayout29);
            app.PitchAngleResLabel = uilabel(app.GridLayout27_8);
            app.PitchAngleResLabel.Text = 'Pitch Angle Res';
            app.PitchAngleResEditField = uieditfield(app.GridLayout27_8, 'text');
            app.MotorResponseTab = uitab(app.TabGroup3);
            app.MotorResponseTab.Title = 'Motor Response';
            app.GridLayout37 = uigridlayout(app.MotorResponseTab);
            app.GridLayout36_2 = uigridlayout(app.GridLayout37);
            app.MotorSpeedPanel = uipanel(app.GridLayout36_2);
            app.MotorSpeedPanel.Title = 'Motor Speed';
            app.GridLayout25_2 = uigridlayout(app.MotorSpeedPanel);
            app.GridLayout26_2 = uigridlayout(app.GridLayout25_2);
            app.motorSpeedIndicator4 = uiaerorpm(app.GridLayout26_2);
            app.motorSpeedIndicator3 = uiaerorpm(app.GridLayout26_2);
            app.GridLayout27_12 = uigridlayout(app.GridLayout26_2);
            app.VertVelocityResLabel_5 = uilabel(app.GridLayout27_12);
            app.VertVelocityResLabel_5.Text = 'Motor 3';
            app.motorSpeedRes3 = uieditfield(app.GridLayout27_12, 'text');
            app.GridLayout27_13 = uigridlayout(app.GridLayout26_2);
            app.VertVelocityResLabel_6 = uilabel(app.GridLayout27_13);
            app.VertVelocityResLabel_6.Text = 'Motor 4';
            app.motorSpeedRes4 = uieditfield(app.GridLayout27_13, 'text');
            app.GridLayout26_3 = uigridlayout(app.GridLayout25_2);
            app.motorSpeedIndicator1 = uiaerorpm(app.GridLayout26_3);
            app.motorSpeedIndicator2 = uiaerorpm(app.GridLayout26_3);
            app.GridLayout27_10 = uigridlayout(app.GridLayout26_3);
            app.VertVelocityResLabel_3 = uilabel(app.GridLayout27_10);
            app.VertVelocityResLabel_3.Text = 'Motor 1';
            app.motorSpeedRes1 = uieditfield(app.GridLayout27_10, 'text');
            app.GridLayout27_11 = uigridlayout(app.GridLayout26_3);
            app.VertVelocityResLabel_4 = uilabel(app.GridLayout27_11);
            app.VertVelocityResLabel_4.Text = 'Motor 2';
            app.motorSpeedRes2 = uieditfield(app.GridLayout27_11, 'text');
            app.TabGroup2 = uitabgroup(app.GridLayout10);
            app.DAnimationTab = uitab(app.TabGroup2);
            app.DAnimationTab.Title = '3D Animation';
            app.GridLayout31 = uigridlayout(app.DAnimationTab);
            app.DMovementAnimationPanel = uipanel(app.GridLayout31);
            app.DMovementAnimationPanel.Title = '3D Movement Animation ';
            app.GridLayout32 = uigridlayout(app.DMovementAnimationPanel);
            app.UIAxes6 = uiaxes(app.GridLayout32);
            title(app.UIAxes6, 'Animation of Quadcopter')
            xlabel(app.UIAxes6, 'X')
            ylabel(app.UIAxes6, 'Y')
            zlabel(app.UIAxes6, 'Z')
            app.DRotationAnimationPanel = uipanel(app.GridLayout31);
            app.DRotationAnimationPanel.Title = '3D Rotation Animation';
            app.GridLayout33 = uigridlayout(app.DRotationAnimationPanel);
            app.UIAxes7 = uiaxes(app.GridLayout33);
            title(app.UIAxes7, 'Animation of Quadcopter')
            xlabel(app.UIAxes7, 'X')
            ylabel(app.UIAxes7, 'Y')
            zlabel(app.UIAxes7, 'Z')
            app.ReferenceResponseTab_2 = uitab(app.TabGroup2);
            app.ReferenceResponseTab_2.Title = 'Reference & Response ';
            app.GridLayout34 = uigridlayout(app.ReferenceResponseTab_2);
            app.zdotResponse = uiaxes(app.GridLayout34);
            title(app.zdotResponse, 'Zdot Reference & Response ')
            xlabel(app.zdotResponse, 'Time')
            ylabel(app.zdotResponse, 'Zdot')
            zlabel(app.zdotResponse, 'Z')
            app.phiResponse = uiaxes(app.GridLayout34);
            title(app.phiResponse, 'Phi Reference & Response ')
            xlabel(app.phiResponse, 'Time')
            ylabel(app.phiResponse, 'Phi')
            zlabel(app.phiResponse, 'Z')
            app.psidotResponse = uiaxes(app.GridLayout34);
            title(app.psidotResponse, 'Psidot Reference & Response ')
            xlabel(app.psidotResponse, 'Time')
            ylabel(app.psidotResponse, 'r')
            zlabel(app.psidotResponse, 'Z')
            app.thetaResponse = uiaxes(app.GridLayout34);
            title(app.thetaResponse, 'Theta Reference & Response ')
            xlabel(app.thetaResponse, 'Time')
            ylabel(app.thetaResponse, 'Theta')
            zlabel(app.thetaResponse, 'Z')
            app.ResponseStateTab = uitab(app.TabGroup2);
            app.ResponseStateTab.Title = 'Response State';
            app.GridLayout35 = uigridlayout(app.ResponseStateTab);
            app.xVerTime = uiaxes(app.GridLayout35);
            xlabel(app.xVerTime, 'Time')
            ylabel(app.xVerTime, 'X')
            zlabel(app.xVerTime, 'Z')
            app.yVerTime = uiaxes(app.GridLayout35);
            xlabel(app.yVerTime, 'Time')
            ylabel(app.yVerTime, 'Y')
            zlabel(app.yVerTime, 'Z')
            app.zVerTime = uiaxes(app.GridLayout35);
            xlabel(app.zVerTime, 'Time')
            ylabel(app.zVerTime, 'Z')
            zlabel(app.zVerTime, 'Z')
            app.phiVerTime = uiaxes(app.GridLayout35);
            xlabel(app.phiVerTime, 'Time')
            ylabel(app.phiVerTime, 'Phi')
            zlabel(app.phiVerTime, 'Z')
            app.thetaVerTime = uiaxes(app.GridLayout35);
            xlabel(app.thetaVerTime, 'Time')
            ylabel(app.thetaVerTime, 'Theta')
            zlabel(app.thetaVerTime, 'Z')
            app.psiVerTime = uiaxes(app.GridLayout35);
            xlabel(app.psiVerTime, 'Time')
            ylabel(app.psiVerTime, 'Psi')
            zlabel(app.psiVerTime, 'Z')
            app.TabGroup = uitabgroup(app.GridLayout9);
            app.ControllerTuningTab = uitab(app.TabGroup);
            app.ControllerTuningTab.Title = 'Controller Tuning';
            app.PIDPanel = uipanel(app.ControllerTuningTab);
            app.PIDPanel.Title = 'PID';
            app.GridLayout16 = uigridlayout(app.PIDPanel);
            app.VerticalVelocityLabel_2 = uilabel(app.GridLayout16);
            app.VerticalVelocityLabel_2.Text = 'Vertical Velocity';
            app.RollAngleLabel = uilabel(app.GridLayout16);
            app.RollAngleLabel.Text = 'Roll Angle';
            app.RollRateLabel = uilabel(app.GridLayout16);
            app.RollRateLabel.Text = 'Roll Rate';
            app.PitchAngleLabel = uilabel(app.GridLayout16);
            app.PitchAngleLabel.Text = 'Pitch Angle';
            app.PitchRateLabel = uilabel(app.GridLayout16);
            app.PitchRateLabel.Text = 'Pitch Rate';
            app.YawRateLabel_2 = uilabel(app.GridLayout16);
            app.YawRateLabel_2.Text = 'Yaw Rate';
            app.PLabel = uilabel(app.GridLayout16);
            app.PLabel.Text = 'P';
            app.ILabel = uilabel(app.GridLayout16);
            app.ILabel.Text = 'I';
            app.DLabel = uilabel(app.GridLayout16);
            app.DLabel.Text = 'D';
            app.KpZdotField = uieditfield(app.GridLayout16, 'text');
            app.KpPhiField = uieditfield(app.GridLayout16, 'text');
            app.KpPhidotField = uieditfield(app.GridLayout16, 'text');
            app.KpThetaField = uieditfield(app.GridLayout16, 'text');
            app.KpThetadotField = uieditfield(app.GridLayout16, 'text');
            app.KpPsidotField = uieditfield(app.GridLayout16, 'text');
            app.KiZdotField = uieditfield(app.GridLayout16, 'text');
            app.KiPhiField = uieditfield(app.GridLayout16, 'text');
            app.KiPhidotField = uieditfield(app.GridLayout16, 'text');
            app.KiThetaField = uieditfield(app.GridLayout16, 'text');
            app.KiThetadotField = uieditfield(app.GridLayout16, 'text');
            app.KiPsidotField = uieditfield(app.GridLayout16, 'text');
            app.KdZdotField = uieditfield(app.GridLayout16, 'text');
            app.KdPhiField = uieditfield(app.GridLayout16, 'text');
            app.KdPhidotField = uieditfield(app.GridLayout16, 'text');
            app.KdThetaField = uieditfield(app.GridLayout16, 'text');
            app.KdThetadotField = uieditfield(app.GridLayout16, 'text');
            app.KdPsidotField = uieditfield(app.GridLayout16, 'text');
            app.TuneButton = uibutton(app.GridLayout16, 'push');
            app.TuneButton.Text = 'Tune';
            app.LQRPanel = uipanel(app.ControllerTuningTab);
            app.LQRPanel.Title = 'LQR';
            app.ReferenceCommandTab = uitab(app.TabGroup);
            app.ReferenceCommandTab.Title = 'Reference Command';
            app.GridLayout17 = uigridlayout(app.ReferenceCommandTab);
            app.CommandLimitationsPanel = uipanel(app.GridLayout17);
            app.CommandLimitationsPanel.Title = 'Command Limitations';
            app.GridLayout18 = uigridlayout(app.CommandLimitationsPanel);
            app.GridLayout19 = uigridlayout(app.GridLayout18);
            app.VerticalVelocitymsEditField_2Label = uilabel(app.GridLayout19);
            app.VerticalVelocitymsEditField_2Label.Text = 'Vertical Velocity (m/s)';
            app.VerticalVelocityLimitField = uieditfield(app.GridLayout19, 'numeric');
            app.VerticalVelocitymsEditField_2Label_2 = uilabel(app.GridLayout19);
            app.VerticalVelocitymsEditField_2Label_2.Text = 'Yaw Rate (deg/s)';
            app.YawRateLimitField = uieditfield(app.GridLayout19, 'numeric');
            app.VerticalVelocitymsEditField_2Label_3 = uilabel(app.GridLayout19);
            app.VerticalVelocitymsEditField_2Label_3.Text = 'Roll Angle (deg)';
            app.RollAngleLimitField = uieditfield(app.GridLayout19, 'numeric');
            app.VerticalVelocitymsEditField_2Label_4 = uilabel(app.GridLayout19);
            app.VerticalVelocitymsEditField_2Label_4.Text = 'Pitch Angle (deg)';
            app.PitchAngleLimitField = uieditfield(app.GridLayout19, 'numeric');
            app.GridLayout30 = uigridlayout(app.GridLayout18);
            app.SetButton = uibutton(app.GridLayout30, 'push');
            app.SetButton.ButtonPushedFcn = createCallbackFcn(app, @SetButtonPushed, true);
            app.SetButton.Text = 'Set';
            app.LimitLamp = uilamp(app.GridLayout30);
            app.GridLayout20 = uigridlayout(app.GridLayout17);
            app.GridLayout21 = uigridlayout(app.GridLayout20);
            app.VerticalVelocityLabel = uilabel(app.GridLayout21);
            app.VerticalVelocityLabel.Text = {'Vertical Velocity '; 'Command (m/s) '};
            app.VerticalVelocityCommandmsEditField = uieditfield(app.GridLayout21, 'text');
            app.VerticalVelocityRefCmdImg = uigauge(app.GridLayout20, 'circular');
            app.GridLayout20_2 = uigridlayout(app.GridLayout17);
            app.GridLayout22 = uigridlayout(app.GridLayout20_2);
            app.YawRateLabel = uilabel(app.GridLayout22);
            app.YawRateLabel.Text = {'Yaw Rate'; 'Command (deg/s)'};
            app.YawRateCommanddegsEditField = uieditfield(app.GridLayout22, 'text');
            app.YawRateRefCmdImg = uigauge(app.GridLayout20_2, 'semicircular');
            app.GridLayout20_3 = uigridlayout(app.GridLayout17);
            app.GridLayout22_2 = uigridlayout(app.GridLayout20_3);
            app.RollCommandLabel = uilabel(app.GridLayout22_2);
            app.RollCommandLabel.Text = {'Roll Command '; '(deg) '};
            app.RollCommanddegEditField = uieditfield(app.GridLayout22_2, 'text');
            app.RollAngleRefCmdImg = uigauge(app.GridLayout20_3, 'semicircular');
            app.GridLayout20_4 = uigridlayout(app.GridLayout17);
            app.GridLayout22_3 = uigridlayout(app.GridLayout20_4);
            app.PitchCommanddegLabel = uilabel(app.GridLayout22_3);
            app.PitchCommanddegLabel.Text = {'Pitch Command'; '(deg)  '};
            app.PitchCommanddegEditField = uieditfield(app.GridLayout22_3, 'text');
            app.PitchAngleRefCmdImg = uigauge(app.GridLayout20_4, 'semicircular');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = JoystickMapping

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end